classdef ImagesAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BACKButton    matlab.ui.control.Button
        BROWSEButton  matlab.ui.control.Button
        DETECTButton  matlab.ui.control.Button
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DETECTButton
        function DetectButton(app, event)
        % Set the line width and color for the rectangle
        rectangle = 'Rectangle';
        lineWidth = 3; % for example
        color = 'red'; % or any color contrasting with your image
        
        
        % Get the image from the Axes
        the_Image = getimage(app.UIAxes);
        
        % Create a cascade detector object.
        faceDetector = vision.CascadeObjectDetector();
        
        % Find the bounding box that encloses the face on the image
        face_Location = step(faceDetector, the_Image);
        
        % Draw the returned bounding box around the detected face.
        the_Image = insertShape(the_Image, rectangle, face_Location, 'LineWidth', lineWidth, 'Color', color);
        
        
        % Clear UIAxes2 and reset its properties
        cla(app.UIAxes2);
        reset(app.UIAxes2);
        
        % Display the image on UI.Axes2
        imshow(the_Image, 'Parent',app.UIAxes2); 


        end

        % Button pushed function: BROWSEButton
        function BrowseButton(app, event)
            
                            % Open the file explorer to select an image file
        [filename, pathname] = uigetfile({'*.jpeg;*.jpg;*.png;*.gif','All Image Files';'*.*','All Files' },'Select an image',...
            'C:\'); 
        
        % Check if a file is selected
        if filename == 0 % no file selected
            return;
        end
        
        % Full path of the file
        full_image_path = fullfile(pathname, filename);
        
        % Load the image
        the_Image = imread(full_image_path);


        imshow(the_Image, 'Parent',app.UIAxes); 
        end

        % Button pushed function: BACKButton
        function BackToMain(app, event)
        %Delete the main interface
        delete(app.UIFigure); 
        % create an instance of the second interface
        InterfaceMain = MainInterface();
        % make it visible
        InterfaceMain.UIFigure.Visible = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UNDETECTED IMAGE')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'DETECTED IMAGE ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.DETECTButton = uibutton(app.UIFigure, 'push');
            app.DETECTButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton, true);
            app.DETECTButton.Text = 'DETECT';
            app.BROWSEButton = uibutton(app.UIFigure, 'push');
            app.BROWSEButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton, true);
            app.BROWSEButton.Text = 'BROWSE';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMain, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagesAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end