classdef LiveFaceDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BACKButton          matlab.ui.control.Button
        Image3              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        FaceDetectionLabel  matlab.ui.control.Label
        STOPButton          matlab.ui.control.Button
        captureButton       matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
      
        WebcamObj          webcam
        IsCapturing        logical
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: captureButton
        function captureButtonPushed(app, event)
          
    app.WebcamObj = webcam();
    app.IsCapturing = true;

    % Créer un objet de détection de visage
    faceDetector = vision.CascadeObjectDetector();

    while app.IsCapturing
        % Capturer une image de la webcam
        frame = snapshot(app.WebcamObj);

        % Convertir le cadre en niveaux de gris  pour la detection de
        % visage
        grayFrame = rgb2gray(frame);

        % Détecter les visages dans le cadre en niveaux de gris
        bbox = step(faceDetector, grayFrame);

        % Draw rectangles around the detected faces
        VisageDetectee = insertShape(frame, 'Rectangle', bbox, 'LineWidth', 3);

        % Afficher le cadre avec les visages détectés
        imshow(VisageDetectee, 'Parent', app.UIAxes);
        drawnow;

        % Faire une pause pendant une courte durée
        pause(0.05);
    end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.IsCapturing = false;
            clear app.WebcamObj;
            cla(app.UIAxes);
            
        end

        % Button pushed function: BACKButton
        function BackToInterface(app, event)
        %Delete the main interface
        delete(app.UIFigure); 
        % create an instance of the second interface
        InterfaceMain = MainInterface();
        % make it visible
        InterfaceMain.UIFigure.Visible = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Screenshot 2023-05-09 131959.png');
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Tag = 'UIAxes';
            app.captureButton = uibutton(app.UIFigure, 'push');
            app.captureButton.ButtonPushedFcn = createCallbackFcn(app, @captureButtonPushed, true);
            app.captureButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.FaceDetectionLabel = uilabel(app.UIFigure);
            app.FaceDetectionLabel.Text = 'Face Detection';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'EIDIA LOGO .png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'EIDIA LOGO.png');
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BackToInterface, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveFaceDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end