classdef MainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image3        matlab.ui.control.Image
        Image2        matlab.ui.control.Image
        VIDEOButton   matlab.ui.control.Button
        IMAGESButton  matlab.ui.control.Button
        CAMERAButton  matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CAMERAButton
        function CameraAPP(app, event)
            
        % Delete the main interface
        delete(app.UIFigure);
        % create an instance of the second interface
        InterfaceCamera = LiveFaceDetection();
        % make it visible
        InterfaceCamera.UIFigure.Visible = 'on';
        
        end

        % Button pushed function: VIDEOButton
        function VideoCall(app, event)
        % Delete the main interface
        delete(app.UIFigure); 
        % create an instance of the second interface
        InterfaceVideo = VideoApp();
        % make it visible
        InterfaceVideo.UIFigure.Visible = 'on';
        end

        % Button pushed function: IMAGESButton
        function ImagesCall(app, event)
        % Delete the main interface
        delete(app.UIFigure);        
        % create an instance of the second interface
        InterfaceImage = ImagesAPP();
        % make it visible
        InterfaceImage.UIFigure.Visible = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blockchain-product-and-service-provider-in-usa-approached-hubino-for-facial-authentication-solution.png';
            app.CAMERAButton = uibutton(app.UIFigure, 'push');
            app.CAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @CameraAPP, true);
            app.CAMERAButton.Tag = 'camera ';
            app.CAMERAButton.Text = 'CAMERA';
            app.IMAGESButton = uibutton(app.UIFigure, 'push');
            app.IMAGESButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesCall, true);
            app.IMAGESButton.Text = 'IMAGES';
            app.VIDEOButton = uibutton(app.UIFigure, 'push');
            app.VIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @VideoCall, true);
            app.VIDEOButton.Tag = 'video ';
            app.VIDEOButton.Text = 'VIDEO';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UEMF.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'EIDIA LOGO .png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end