classdef VideoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BACKButton          matlab.ui.control.Button
        DETECTEDVIDEOLabel  matlab.ui.control.Label
        BROWSEButton        matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BROWSEButton
        function DetectButton(app, event)

         % Set the line width and color for the rectangle
        rectangle = 'Rectangle';
        lineWidth = 3; % for example
        color = 'red'; % or any color contrasting with your image
        % Create a cascade detector object.
        faceDetector = vision.CascadeObjectDetector();
    
        % Open the file explorer to select a video file
        [filename, pathname] = uigetfile({'*.mp4','All Video Files';'*.*','All Files' },'Select a video',...
        'C:\'); 
    
        % Check if a file is selected
        if filename == 0 % no file selected
            return;
        end
    
        % Full path of the file
        full_video_path = fullfile(pathname, filename);
    
        % Read a video frame and run the face detector.
        videoReader = VideoReader(full_video_path);
        
        while hasFrame(videoReader)
            % Get the next frame.
            videoFrame = readFrame(videoReader);
    
            % Run the face detector on the current frame
            faceLocation = step(faceDetector, videoFrame);
    
            % Draw the returned bounding box around the detected face.
            detectedFrame = insertShape(videoFrame, rectangle, faceLocation, 'LineWidth', lineWidth, 'Color', color);
    
            % your video processing code...
    
            % Display the annotated video frame in the axes object
            imshow(detectedFrame, 'Parent', app.UIAxes);
            
            % Pause execution for a short time to create a visible frame rate
            pause(1/videoReader.FrameRate);
        end


        end

        % Button pushed function: BACKButton
        function BackToMain(app, event)
        %Delete the main interface
        delete(app.UIFigure); 
        % create an instance of the second interface
        InterfaceMain = MainInterface();
        % make it visible
        InterfaceMain.UIFigure.Visible = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BROWSEButton = uibutton(app.UIFigure, 'push');
            app.BROWSEButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton, true);
            app.BROWSEButton.Text = 'BROWSE';
            app.DETECTEDVIDEOLabel = uilabel(app.UIFigure);
            app.DETECTEDVIDEOLabel.Text = 'DETECTED VIDEO';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMain, true);
            app.BACKButton.Text = 'BACK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end